classdef MastersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FeatureLocationinBodyframemetersPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        xyLabel_6                       matlab.ui.control.Label
        Point6EditFieldy                matlab.ui.control.NumericEditField
        Point6EditFieldx                matlab.ui.control.NumericEditField
        Point4EditFieldLabel_2          matlab.ui.control.Label
        xyLabel_5                       matlab.ui.control.Label
        Point5EditFieldy                matlab.ui.control.NumericEditField
        Point5EditFieldx                matlab.ui.control.NumericEditField
        Point6Label                     matlab.ui.control.Label
        Point4EditFieldx                matlab.ui.control.NumericEditField
        PURPLELabel                     matlab.ui.control.Label
        Point3EditFieldx                matlab.ui.control.NumericEditField
        BLUELabel                       matlab.ui.control.Label
        Point2EditFieldx                matlab.ui.control.NumericEditField
        GREENLabel                      matlab.ui.control.Label
        Point1EditFieldx                matlab.ui.control.NumericEditField
        REDLabel                        matlab.ui.control.Label
        xyLabel_4                       matlab.ui.control.Label
        xyLabel_3                       matlab.ui.control.Label
        xyLabel_2                       matlab.ui.control.Label
        xyLabel                         matlab.ui.control.Label
        Point4EditFieldy                matlab.ui.control.NumericEditField
        Point3EditFieldy                matlab.ui.control.NumericEditField
        Point2EditFieldy                matlab.ui.control.NumericEditField
        Point1EditFieldy                matlab.ui.control.NumericEditField
        CreateCAMExtrinsicsPanel        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EULERZYXEditFieldLabel          matlab.ui.control.Label
        CAMERAExtrinsicsListBox         matlab.ui.control.ListBox
        CAMERALabel                     matlab.ui.control.Label
        posZ                            matlab.ui.control.NumericEditField
        posY                            matlab.ui.control.NumericEditField
        eulZ                            matlab.ui.control.NumericEditField
        posX                            matlab.ui.control.NumericEditField
        EULERZYXEditFieldLabel_2        matlab.ui.control.Label
        eulX                            matlab.ui.control.NumericEditField
        eulY                            matlab.ui.control.NumericEditField
        AutoIntrinsicsButton            matlab.ui.control.Button
        FPSLabel                        matlab.ui.control.Label
        SetExtrinsicsButton             matlab.ui.control.Button
        TESTEKFButton                   matlab.ui.control.Button
        RUNEKFButton                    matlab.ui.control.Button
        SETButton_2                     matlab.ui.control.Button
        TOTALTIMESECONDSSpinner         matlab.ui.control.Spinner
        TIMELabel                       matlab.ui.control.Label
        CAMERAListBox                   matlab.ui.control.ListBox
        CAMERAListBoxLabel              matlab.ui.control.Label
        CamerasinuseDropDown            matlab.ui.control.DropDown
        CamerasinuseDropDownLabel       matlab.ui.control.Label
        CAMEXTRINSICSSwitch             matlab.ui.control.Switch
        CAMEXTRINSICSSwitchLabel        matlab.ui.control.Label
        SerialPortListListBox           matlab.ui.control.ListBox
        SerialPortListListBoxLabel      matlab.ui.control.Label
        RESETALLButton                  matlab.ui.control.Button
        CLEARCALIBRATIONMATRICESButton  matlab.ui.control.Button
        SetWorldPointsButton            matlab.ui.control.Button
        GetExtrinsicsButton             matlab.ui.control.Button
        SetIntrinsicsButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IntrinsicsTab                   matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ExtrinsicsTab                   matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        DISCONNECTALLButton             matlab.ui.control.Button
        ConnectionlistPanel             matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        PORTLabel_6                     matlab.ui.control.Label
        PORTLabel_5                     matlab.ui.control.Label
        PORTLabel_4                     matlab.ui.control.Label
        PORTLabel_3                     matlab.ui.control.Label
        PORTLabel_2                     matlab.ui.control.Label
        PORTLabel_1                     matlab.ui.control.Label
        CAMERALAMP6                     matlab.ui.control.Lamp
        CAMERA6LampLabel                matlab.ui.control.Label
        CAMERALAMP5                     matlab.ui.control.Lamp
        CAMERA5LampLabel                matlab.ui.control.Label
        CAMERALAMP4                     matlab.ui.control.Lamp
        CAMERA4LampLabel                matlab.ui.control.Label
        CAMERALAMP3                     matlab.ui.control.Lamp
        CAMERA3LampLabel                matlab.ui.control.Label
        CAMERALAMP2                     matlab.ui.control.Lamp
        CAMERA2LampLabel                matlab.ui.control.Label
        CAMERALAMP1                     matlab.ui.control.Lamp
        CAMERA1LampLabel                matlab.ui.control.Label
        SETButton                       matlab.ui.control.Button
        ConnectportButton               matlab.ui.control.Button
        UIAxesCAM                       matlab.ui.control.UIAxes
        UIAxes3D                        matlab.ui.control.UIAxes
        UIAxes2D                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Description
        port = [];
        iPortAvail = 0;
        EKF;
        openmv1;
        openmv2;
        openmv3;
        openmv4;
        openmv5;
        openmv6;
        bDataCAM1 = true;
        bDataCAM2 = true;
        bDataCAM3 = true;
        bDataCAM4 = true;
        bDataCAM5 = true;
        bDataCAM6 = true;
        connectedCams = 0;
        totalCameras = 0;
        CamParams1;
        CamParams2;
        CamParams3;
        CamParams4;
        CamParams5;
        CamParams6;
        calibCamParams1;
        calibCamParams2;
        calibCamParams3;
        calibCamParams4;
        calibCamParams5;
        calibCamParams6;
        bodyFeat1;
        bodyFeat2;
        bodyFeat3;
        bodyFeat4;
        bodyFeat5;
        bodyFeat6;
        extrinsicsCAM1;
        extrinsicsCAM2;
        extrinsicsCAM3;
        extrinsicsCAM4;
        extrinsicsCAM5;
        extrinsicsCAM6;
        C1;
        C2;
        C3;
        C4;
        C5;
        C6;
        MaxTime;
        TrueVal;
    end
    
    methods (Access = public)

        %STEP 1: Xkp = AXk-1+BUk+Wk

        function A = sftA(app,dT)
            A = [1 0 0 dT 0 0; 
                 0 1 0 0 dT 0; 
                 0 0 1 0 0 dT; 
                 0 0 0 1 0 0; 
                 0 0 0 0 1 0; 
                 0 0 0 0 0 1];
        end

        function Xkp = sftXkp(app,A,Xk)
            Xkp = A*Xk;
        end

        %STEP 2A: P(1) = [6x6] - only done for first iteration.
        function P = sftinitialP(app,eX,eY,eZ,eVx,eVy,eVz)
            P = [eX^2 0 0 0 0 0; 
                  0 eY^2 0 0 0 0; 
                  0 0 eZ^2 0 0 0; 
                  0 0 0 eVx^2 0 0; 
                  0 0 0 0 eVy^2 0; 
                  0 0 0 0 0 eVz^2];
        end
        
        %STEP 2: Pkp = A*Pk*A'+Qk
        function Qk = sftQk(app,dT,eAx,eAy,eAz)
            G = [0 0 0;
                0 0 0;
                0 0 0;
                dT 0 0;
                0 dT 0;
                0 0 dT];
            Q = [eAx^2 0 0; 
                 0 eAy^2 0; 
                 0 0 eAz^2];
            Qk = G*Q*G';
        end

        function Pkp = sftPkp(app,A,Pk,Qk)
            Pkp = A*Pk*A'+Qk;
        end
        
        %STEP 3A: H = [2nC x 6] - involves Cn matrix where n = 1:6.
        function createinvC(app,numCAM)
            for k = 1:numCAM
                Clabel = ['C',num2str(k)];
                CamParams = ['CamParams',num2str(k)];
                extrinsics = ['extrinsicsCAM',num2str(k)];
                K = [app.(CamParams).K zeros(3,1)];
                T = app.(extrinsics);
                invT = T\eye(4);
                C = K*invT;
                C = C/C(3,4);
                app.(Clabel) = C;

                %CHECKED AND IS WORKING CORRECTLY!
            end
        end
        
        function results = sftH(app,numCAMFail,numCAM,Xkp)
            x = Xkp(1);
            y = Xkp(2);
            z = Xkp(3);
            H = zeros(2*(numCAM-numCAMFail),6);
            nF = 0;
            for k = 1:numCAM
                bDataCAM = ['bDataCAM',num2str(k)];
                if app.(bDataCAM) == true
                    Clabel = ['C',num2str(k)];
                    H(2*(k-nF)-1:2*(k-nF),1:6) = [(app.(Clabel)(1,1)*app.(Clabel)(3,4) - app.(Clabel)(1,4)*app.(Clabel)(3,1) + app.(Clabel)(1,1)*app.(Clabel)(3,2)*y - app.(Clabel)(1,2)*app.(Clabel)(3,1)*y + app.(Clabel)(1,1)*app.(Clabel)(3,3)*z - app.(Clabel)(1,3)*app.(Clabel)(3,1)*z)/(app.(Clabel)(3,4) + app.(Clabel)(3,1)*x + app.(Clabel)(3,2)*y + app.(Clabel)(3,3)*z)^2 (app.(Clabel)(1,2)*app.(Clabel)(3,4) - app.(Clabel)(1,4)*app.(Clabel)(3,2) - app.(Clabel)(1,1)*app.(Clabel)(3,2)*x + app.(Clabel)(1,2)*app.(Clabel)(3,1)*x + app.(Clabel)(1,2)*app.(Clabel)(3,3)*z - app.(Clabel)(1,3)*app.(Clabel)(3,2)*z)/(app.(Clabel)(3,4) + app.(Clabel)(3,1)*x + app.(Clabel)(3,2)*y + app.(Clabel)(3,3)*z)^2 (app.(Clabel)(1,3)*app.(Clabel)(3,4) - app.(Clabel)(1,4)*app.(Clabel)(3,3) - app.(Clabel)(1,1)*app.(Clabel)(3,3)*x + app.(Clabel)(1,3)*app.(Clabel)(3,1)*x - app.(Clabel)(1,2)*app.(Clabel)(3,3)*y + app.(Clabel)(1,3)*app.(Clabel)(3,2)*y)/(app.(Clabel)(3,4) + app.(Clabel)(3,1)*x + app.(Clabel)(3,2)*y + app.(Clabel)(3,3)*z)^2 0 0 0;
                        (app.(Clabel)(2,1)*app.(Clabel)(3,4) - app.(Clabel)(2,4)*app.(Clabel)(3,1) + app.(Clabel)(2,1)*app.(Clabel)(3,2)*y - app.(Clabel)(2,2)*app.(Clabel)(3,1)*y + app.(Clabel)(2,1)*app.(Clabel)(3,3)*z - app.(Clabel)(2,3)*app.(Clabel)(3,1)*z)/(app.(Clabel)(3,4) + app.(Clabel)(3,1)*x + app.(Clabel)(3,2)*y + app.(Clabel)(3,3)*z)^2 (app.(Clabel)(2,2)*app.(Clabel)(3,4) - app.(Clabel)(2,4)*app.(Clabel)(3,2) - app.(Clabel)(2,1)*app.(Clabel)(3,2)*x + app.(Clabel)(2,2)*app.(Clabel)(3,1)*x + app.(Clabel)(2,2)*app.(Clabel)(3,3)*z - app.(Clabel)(2,3)*app.(Clabel)(3,2)*z)/(app.(Clabel)(3,4) + app.(Clabel)(3,1)*x + app.(Clabel)(3,2)*y + app.(Clabel)(3,3)*z)^2 (app.(Clabel)(2,3)*app.(Clabel)(3,4) - app.(Clabel)(2,4)*app.(Clabel)(3,3) - app.(Clabel)(2,1)*app.(Clabel)(3,3)*x + app.(Clabel)(2,3)*app.(Clabel)(3,1)*x - app.(Clabel)(2,2)*app.(Clabel)(3,3)*y + app.(Clabel)(2,3)*app.(Clabel)(3,2)*y)/(app.(Clabel)(3,4) + app.(Clabel)(3,1)*x + app.(Clabel)(3,2)*y + app.(Clabel)(3,3)*z)^2 0 0 0];
                else
                    nF = nF+1;
                    continue;
                end
            end
            results = H;
        end
        
        %STEP 3: KG = (Pkp*H')/(H*Pkp*H'+R)
        function results = sftR(app,numCAMFail,numCAM)
            R = zeros(2*(numCAM-numCAMFail));
            nF = 0;
            for k = 1:numCAM
                bDataCAM = ['bDataCAM',num2str(k)];
                if app.(bDataCAM) == true
                    CamParams= ['CamParams',num2str(k)];
                    R(2*(k-nF),2*(k-nF)) = (app.(CamParams).MeanReprojectionError)^2;
                    R(2*(k-nF)-1,2*(k-nF)-1) = (app.(CamParams).MeanReprojectionError)^2;
                else
                    nF = nF+1;
                    continue;
                end
            end
            disp('R')
            disp(R)
            results = R;
        end

        function KG = sftKG(app,Pkp,H,R)
            KG = (Pkp*H')/(H*Pkp*H'+R);
            disp('KG:')
            disp(KG)
        end    

        %STEP 4 - for now, might look into using parfor.
        function results = sftYk(app,Ykm,numCAMFail,numCAM)
            Yk = zeros(2*(numCAM-numCAMFail),1);
            nF = 0;
            for k = 1:numCAM
                bDataCAM = ['bDataCAM',num2str(k)];
                if app.(bDataCAM) == true
                    CamParams = ['CamParams',num2str(k)];
                    Yk(2*(k-nF)-1:2*(k-nF),1) = [undistortPoints(Ykm(k:k+1).',app.(CamParams))].';
                else
                    nF = nF+1;
                    continue;
                end
            end
            results = Yk;
        end

        %STEP 5A: H_Xkp = [U1 V1 W1 ... Un Vn Wn]' = [C1 ... Cn]' * [x y z 1]'
        % C* = [3x4] = C_x, n = numCAM
        function results = sftC_X(app,numCAMFail,numCAM)
           C_x = zeros(3*(numCAM-numCAMFail),4);
           nF = 0;
           for k = 1:numCAM
               bDataCAM = ['bDataCAM',num2str(k)];
               if app.(bDataCAM) == true
                   Clabel = ['C',num2str(k)];
                   C_x(3*(k-nF)-2:3*(k-nF),:) = app.(Clabel);
               else
                   nF = nF+1;
                   continue;
               end
           end
           results = C_x;
        end

        function UVW = sftUVW(app,C_x,Xkp)
            X_kp = [Xkp(1:3);1];
            UVW = C_x*X_kp;
        end

        function HXkp = sftHXkp(app,numCAMFail,numCAM,UVW)
            W = zeros(2*(numCAM-numCAMFail),3*(numCAM-numCAMFail));
            nF = 0;
            for k = 1:numCAM
                bDataCAM = ['bDataCAM',num2str(k)];
                if app.(bDataCAM) == true
                    W(2*(k-nF)-1,3*(k-nF)-2) = 1/UVW(3*(k-nF));
                    W(2*(k-nF),3*(k-nF)-1) = 1/UVW(3*(k-nF));
                else
                    nF = nF + 1;
                    continue;
                end
            end
            HXkp = W*UVW;
        end

        %STEP 5: Xk = Xkp + KG*(Yk - HXkp);
        function Xk = sftXk(app,Xkp,KG,Yk,HXkp)
            Xk = Xkp + KG*(Yk - HXkp);
        end

        %STEP 6: Pk = (I - KG*H)*Pkp
        function Pk = sftPk(app,KG,H,Pkp)
            Pk = (eye(6,6) - KG*H)*Pkp;
        end

        %STEP 7: UPDATE - DONE IN CODE
        
        %RECIEVE DATA FUNCTION (from UART)
        % function results = getData(app,openmv)
        %     disp(openmv);
        %     isData = openmv.NumBytesAvailable;
        %     if (isData > 0)
        %         data = readline(openmv);
        %         myData = str2double(split(data,','));
        %         results = myData;
        %         disp(myData);
        %     end
        % end       

        %RECIEVE DATA FUNCTION + BOOLEAN IF DIDN'T GET ANY DATA FROM UART
        function [bool,results] = getData(app,openmv)
            try
                data = readline(openmv);
                myData = str2double(split(data,','));
                results = myData;
                bool = true;
            catch
                results = [0 0 0]; %cx,cy,FPS
                bool = false;
            end
        end

        function [nCAMFail,dataArray,FPS] = sortData(app,numCAM)
            nF = 0;
            fullData = zeros(2*numCAM,1);
            FPSCalc = 0;
            for k = 1:numCAM
                openmv = ['openmv',num2str(k)];
                [bool,data] = app.getData(app.(openmv));               
                bDataCAM = ['bDataCAM',num2str(k)];
                if bool == false
                    nF = nF+1;
                end
                app.(bDataCAM) = bool;         
                fullData(2*k-1) = data(1);
                fullData(2*k) = data(2);
                FPSCalc = FPSCalc + data(3);
            end
            nCAMFail = nF;
            dataArray = fullData;
            FPS = FPSCalc/(numCAM-nF);
        end

        function results = extData(app,openmv)
            data = readline(openmv);
            disp(data);
            myData = str2double(split(data,','));
            disp(myData);
            results = myData;
        end
        
        %SIMULATION CREATION
        function testData = testData(app,numCAM)
            n_iterations = 100;
            n_uv=50;
            %x true
            x_t = linspace(-pi/2,pi/2,n_uv);
            x_movement = (2)*(sin(x_t)+1)/2;
            x_rest = x_movement(n_uv).*ones(1,n_iterations-n_uv);
            x_true = [x_movement x_rest];
            %y true
            y_t = linspace(-pi/2,pi/2,n_uv);
            y_movement = (2)*(sin(y_t)+1)/2;
            y_rest = y_movement(n_uv).*ones(1,n_iterations-n_uv);
            y_true = [y_movement y_rest];
            %z true
            z_t = linspace(-pi/2,pi/2,n_uv);
            z_movement = (2)*(sin(z_t)+1)/2;
            z_rest = z_movement(n_uv).*ones(1,n_iterations-n_uv);
            z_true = [z_movement z_rest];
            app.TrueVal = [x_true;y_true;z_true];
            
            %true position & orientation
            true_position = zeros(3,n_iterations);
            camera_pos = zeros(4,n_iterations);
            for k=1:n_iterations
                true_position(:,k) = [x_true(k) y_true(k) z_true(k)]';
                camera_pos(:,k) = [x_true(k) y_true(k) z_true(k) 1]';
            end
            UV = zeros(2*numCAM,n_iterations);
            UVW = zeros(3,n_iterations);
            for k = 1:numCAM
                Clabel = ['C',num2str(k)];
                for i = 1:n_iterations
                    UVW(:,i) = app.(Clabel)*camera_pos(:,i);
                end
                UV(2*k-1,:) = UVW(1,:)./UVW(3,:);
                UV(2*k,:) = UVW(2,:)./UVW(3,:);
                disp(Clabel);
                disp(UVW);
                
            end
            testData = round(UV);  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.port = serialportlist;
            deleteport = {'/dev/cu', 'Bluetooth', 'SLAB', 'Free'};
            for k = 1:length(deleteport)
                idx = contains(app.port,deleteport(k));
                app.port(idx) = [];
            end
            app.TESTEKFButton.Enable = 'off';
            app.RUNEKFButton.Enable = 'off';
            app.SetExtrinsicsButton.Enable = 'off';
            app.SerialPortListListBox.Items = app.port;
            app.iPortAvail = length(app.port);
            app.CAMEXTRINSICSSwitchValueChanged();
            app.CAMEXTRINSICSSwitch.Enable = 'off';
            app.CreateCAMExtrinsicsPanel.Enable = 'off';
            app.CamerasinuseDropDown.Enable = 'on';
            app.SETButton.Enable = 'on';
            app.ConnectportButton.Enable = 'off';
            app.SerialPortListListBox.Enable = 'off';
            app.SetIntrinsicsButton.Enable = 'off';
            app.AutoIntrinsicsButton.Enable = 'off';
            app.CAMERAListBox.Enable = 'off';
            app.SetWorldPointsButton.Enable = 'off';
            app.FeatureLocationinBodyframemetersPanel.Enable = 'off';
            app.GetExtrinsicsButton.Enable = 'off';
            app.DISCONNECTALLButton.Enable = 'off';
            app.CLEARCALIBRATIONMATRICESButton.Enable = 'off';
            app.RESETALLButton.Enable = 'off';
            for k = 1:6
                label = ['PORTLabel_',num2str(k)];
                lamp = ['CAMERALAMP',num2str(k)];
                app.(label).Text = 'PORT';
                app.(lamp).Color = [0.47 0.67 0.19];
                app.(label).Enable = 'off';
                app.(lamp).Enable = 'off';
            end
        end

        % Button pushed function: ConnectportButton
        function ConnectportButtonPushed(app, event)
            connectPort = app.SerialPortListListBox.Value;
            totalCam = str2double(app.totalCameras);
            app.connectedCams = app.connectedCams+1;
            if totalCam >= app.connectedCams
                try 
                    openmv = ['openmv',num2str(app.connectedCams)];
                    app.(openmv) = serialport(connectPort,115200);
                catch
                    app.connectedCams = app.connectedCams-1;
                    return;
                end
                portLabel = ['PORTLabel_',num2str(app.connectedCams)];
                portLabel = app.(portLabel);
                portLabel.Text = connectPort;
                
                lampName = ['CAMERALAMP', num2str(app.connectedCams)];
                lamp = app.(lampName);
                lamp.Color = [0 1 0];

                items = app.SerialPortListListBox.Items;
                app.SerialPortListListBox.Items = items(~ismember(items, connectPort));

            else
                app.connectedCams = app.connectedCams-1;
            end
            if totalCam == app.connectedCams
                app.ConnectportButton.Enable = 'off';
                app.SerialPortListListBox.Enable = 'off';
                app.AutoIntrinsicsButton.Enable = 'on';
            end

            if (strcmp(app.ConnectportButton.Enable,'off')) && (strcmp(app.SetIntrinsicsButton.Enable,'off')) && (strcmp(app.SetWorldPointsButton.Enable,'off'))
                app.GetExtrinsicsButton.Enable = 'on';
            end
        end

        % Value changed function: CAMEXTRINSICSSwitch
        function CAMEXTRINSICSSwitchValueChanged(app, event)
            bExtrinsics = app.CAMEXTRINSICSSwitch.Value;
            if (bExtrinsics)
                app.CreateCAMExtrinsicsPanel.Visible = 'on';
                app.SetExtrinsicsButton.Visible = 'on';
                app.SetWorldPointsButton.Visible = 'off';
                app.GetExtrinsicsButton.Visible = 'off';
                app.FeatureLocationinBodyframemetersPanel.Visible = 'off';
            else
                app.CreateCAMExtrinsicsPanel.Visible = 'off';
                app.SetExtrinsicsButton.Visible = 'off';
                app.SetWorldPointsButton.Visible = 'on';
                app.GetExtrinsicsButton.Visible = 'on';
                app.FeatureLocationinBodyframemetersPanel.Visible = 'on';
            end
        end

        % Button pushed function: SETButton
        function SETButtonPushed2(app, event)
                % Get the selected value from the DropDown
                app.CAMEXTRINSICSSwitch.Enable = 'on';
                app.RESETALLButton.Enable = 'on';
                app.DISCONNECTALLButton.Enable = 'on';
                app.CLEARCALIBRATIONMATRICESButton.Enable = 'on';
                app.SETButton.Enable = 'off';
                app.CamerasinuseDropDown.Enable = 'off';
                selectedValue = app.CamerasinuseDropDown.Value;
                app.totalCameras = selectedValue;
    % Determine the number of lamps to enable based on the selected value
                numLamps = str2double(selectedValue);
    
    % Enable or disable the lamps using a for loop
                for lampIndex = 1:6
                    lampName = ['CAMERALAMP', num2str(lampIndex)];
                    lamp = app.(lampName);  % Get the lamp component by its name
                    label = ['PORTLabel_',num2str(lampIndex)];
                    label = app.(label);
        % Enable or disable the lamp based on its index
                    if lampIndex <= numLamps
                        lamp.Enable = 'on';
                        label.Enable = 'on';
                        label.Text = 'CONNECTION READY';
                    else
                        lamp.Enable = 'off';
                        label.Enable = 'off';
                        label.Text = 'PORT';
                    end
                end
                camTable = app.UITable.Data;
                N = str2double(app.totalCameras);
                cameraList = cell(1, N);
                for i = 1:N
                    cameraList{i} = ['CAM ', num2str(i)];
                end
                cameraList = string(cameraList);
                if ~isempty(camTable)
                    firstColumnData = camTable(:, 1);
                for i = 1:numel(firstColumnData)
                    value = firstColumnData{i};
                    if ismember(value, cameraList)
                    cameraList = cameraList(~strcmp(cameraList, value));
                    end
                end
                end

% Assign the string array to the Items property of the ListBox component
                app.CAMERAListBox.Items = cameraList;
                app.CAMERAExtrinsicsListBox.Items = cameraList; %CHECK
                app.SerialPortListListBox.Enable = 'on';
                app.ConnectportButton.Enable = 'on';
                app.SetIntrinsicsButton.Enable = 'on';
                app.CAMERAListBox.Enable = 'on';
                app.SetWorldPointsButton.Enable = 'on';
                app.FeatureLocationinBodyframemetersPanel.Enable = 'on';
                app.SetExtrinsicsButton.Enable = 'on';
                app.CreateCAMExtrinsicsPanel.Enable = 'on';
                if ~isempty(app.CAMERAListBox.Items)
                    app.GetExtrinsicsButton.Enable = 'off';
                end
        end

        % Button pushed function: DISCONNECTALLButton
        function DISCONNECTALLButtonPushed(app, event)
                for lampIndex = 1:app.connectedCams
                    lampName = ['CAMERALAMP', num2str(lampIndex)];
                    lamp = app.(lampName);  % Get the lamp component by its name
                    label = ['PORTLabel_',num2str(lampIndex)];
                    label = app.(label);
        % Enable or disable the lamp based on its index
                    lamp.Enable = 'on';
                    lamp.Color = [0.47 0.67 0.19];
                    label.Enable = 'on';
                    label.Text = 'CONNECTION READY';
                end
                app.port = serialportlist;
                deleteport = {'/dev/cu', 'Bluetooth', 'SLAB', 'Free'};
                for k = 1:length(deleteport)
                    idx = contains(app.port,deleteport(k));
                    app.port(idx) = [];
                end
                app.SerialPortListListBox.Items = app.port;
                app.iPortAvail = length(app.port);
                app.SerialPortListListBox.Enable = 'on';
                app.ConnectportButton.Enable = 'on';

                for k = 1:app.connectedCams
                    openmv = ['openmv',num2str(k)];
                    openmv = app.(openmv);
                    delete(openmv);
                end
                app.GetExtrinsicsButton.Enable = 'off';
                app.connectedCams = 0;
        end

        % Button pushed function: SetIntrinsicsButton
        function SetIntrinsicsButtonPushed(app, event)
            if isempty(app.CAMERAListBox.Value)
                return;
            else
                
                CAMName = app.CAMERAListBox.Value;
                CAMNum = extractAfter(CAMName,'CAM ');

                CLabel = ['CamParams',CAMNum];
                openmv = ['openmv',CAMNum];
                
                [filename,filepath] = uigetfile('*.mat');
                if isequal(filename, 0) || isequal(filepath, 0)
                    return;
                end

                    
                fullFilePath = fullfile(filepath, filename);
                load(fullFilePath,'-mat');
                try
                    app.(CLabel) = cameraParams;
                    app.(openmv).write('INT','string');
                catch
                    errordlg("Please select a workspace with 'cameraParams' struct.")
                    return;
                end
                app.UITable.Data = [app.UITable.Data;{CAMName,filename}];
                CAMitems = app.CAMERAListBox.Items;
                app.CAMERAListBox.Items = CAMitems(~ismember(CAMitems, CAMName));                    
            end
            if isempty(app.CAMERAListBox.Items)
                app.SetIntrinsicsButton.Enable = 'off';
                app.CAMERAListBox.Enable = 'off';
                app.TESTEKFButton.Enable = 'on';
            end
            if (strcmp(app.ConnectportButton.Enable,'off')) && (strcmp(app.SetIntrinsicsButton.Enable,'off')) && (strcmp(app.SetWorldPointsButton.Enable,'off'))
                app.GetExtrinsicsButton.Enable = 'on';
            end
            app.TabGroup.SelectedTab = app.IntrinsicsTab;   
        end

        % Button pushed function: CLEARCALIBRATIONMATRICESButton
        function CLEARCALIBRATIONMATRICESButtonPushed(app, event)
            for k = 1:6
                CamParams = ['CamParams',num2str(k)];
                app.(CamParams) = struct();
                CamExt = ['extrinsicsCAM',num2str(k)];
                app.(CamExt) = 0;
            end
            app.UITable.Data = {};
            app.UITable2.Data = {};
            N = str2double(app.totalCameras);
            cameraList = cell(1, N);
            for i = 1:N
                cameraList{i} = ['CAM ', num2str(i)];
            end
            app.CAMERAListBox.Items = cameraList;
            app.CAMERAExtrinsicsListBox.Items = cameraList;
            app.SetIntrinsicsButton.Enable = 'on';
            app.AutoIntrinsicsButton.Enable = 'on';
            app.SetWorldPointsButton.Enable = 'on';
            app.CAMERAListBox.Enable = 'on';
            app.FeatureLocationinBodyframemetersPanel.Enable = 'on';
            app.GetExtrinsicsButton.Enable = 'off';
            app.SetExtrinsicsButton.Enable = 'on';
            app.CreateCAMExtrinsicsPanel.Enable = 'on';
            app.CAMEXTRINSICSSwitch.Enable = 'on';
            app.TESTEKFButton.Enable = 'off';
        end

        % Button pushed function: GetExtrinsicsButton
        function GetExtrinsicsButtonPushed(app, event)
            %try applying parfor to do this - could do each camera at
            %same time.
            app.GetExtrinsicsButton.Enable = 'off';
            numCAM = str2double(app.totalCameras);
            worldPoints = [app.bodyFeat1;app.bodyFeat2;app.bodyFeat3;app.bodyFeat4;app.bodyFeat5;app.bodyFeat6];
            disp(worldPoints);
            for k = 1:numCAM
                disp(['CAM',num2str(k)])
                CamParams = ['calibCamParams',num2str(k)];
                openmv = ['openmv',num2str(k)];
                bDone = true;
                nAttempts = 1;
                while (bDone) && (nAttempts <= 10)
                    try
                        disp(['Attempt number: ',num2str(nAttempts)]);
                        app.(openmv).write('EXT','string');
                        flush(app.(openmv))
                        data = app.extData(app.(openmv));
                        disp(data)
                        %remember to replace cameraParams with CamParams!
                        UV_1 = undistortPoints([data(1) data(2)],app.(CamParams));
                        UV_2 = undistortPoints([data(3) data(4)],app.(CamParams));
                        UV_3 = undistortPoints([data(5) data(6)],app.(CamParams));
                        UV_4 = undistortPoints([data(7) data(8)],app.(CamParams));
                        UV_5 = undistortPoints([data(9) data(10)],app.(CamParams));
                        UV_6 = undistortPoints([data(11) data(12)],app.(CamParams));
                        imagePoints = [UV_1;UV_2;UV_3;UV_4;UV_5;UV_6];
                        extrinsics = estimateExtrinsics(imagePoints,worldPoints,app.(CamParams).Intrinsics);
                        camPose = extr2pose(extrinsics);
                        extrinsicsLabel = ['extrinsicsCAM',num2str(k)];
                        app.(extrinsicsLabel) = camPose.A;
                        rotationMatrix = camPose.R;
                        eulAngles = rotm2eul(rotationMatrix,"ZYX");
                        eulstring = [num2str(rad2deg(eulAngles(1))),' ',num2str(rad2deg(eulAngles(2))),' ',num2str(rad2deg(eulAngles(3)))];
                        translationVec = camPose.Translation;
                        tstring = [num2str(translationVec(1)),' ',num2str(translationVec(2)),' ',num2str(translationVec(3))];
                        CAMName = ['CAM',num2str(k)];
                        app.UITable2.Data = [app.UITable2.Data;{CAMName,eulstring,tstring}];
                        disp(extrinsics.A);
                        pause(0.5);
                        bDone = false;
                        disp('SUCCESS')
                        app.(openmv).write('EXT_SUCCESS','string');
                    catch
                        nAttempts = nAttempts + 1;
                        bDone = true;
                        disp('Not detecting 6 points, put points in camera frame. Pausing for 5 seconds...');
                        disp('')
                        pause(5)
                    end
                end
                if nAttempts>10
                    disp(['Failed to get CAM',num2str(k),' extrinsics, redo extriniscs.'])
                    app.GetExtrinsicsButton.Enable = 'on';
                    break
                end
            end
            app.RUNEKFButton.Enable = 'on';
        end

        % Button pushed function: SetWorldPointsButton
        function SetWorldPointsButtonPushed(app, event)
            app.bodyFeat1 = [app.Point1EditFieldx.Value app.Point1EditFieldy.Value];
            app.bodyFeat2 = [app.Point2EditFieldx.Value app.Point2EditFieldy.Value];
            app.bodyFeat3 = [app.Point3EditFieldx.Value app.Point3EditFieldy.Value];
            app.bodyFeat4 = [app.Point4EditFieldx.Value app.Point4EditFieldy.Value];
            app.bodyFeat5 = [app.Point5EditFieldx.Value app.Point5EditFieldy.Value];
            app.bodyFeat6 = [app.Point6EditFieldx.Value app.Point6EditFieldy.Value];

            app.SetWorldPointsButton.Enable = 'off';
            app.FeatureLocationinBodyframemetersPanel.Enable = 'off';
            app.CAMEXTRINSICSSwitch.Enable = 'off';

            if (strcmp(app.ConnectportButton.Enable,'off')) && (strcmp(app.SetIntrinsicsButton.Enable,'off')) && (strcmp(app.SetWorldPointsButton.Enable,'off'))
                app.GetExtrinsicsButton.Enable = 'on';
            end
        end

        % Button pushed function: RESETALLButton
        function RESETALLButtonPushed(app, event)
            app.CLEARCALIBRATIONMATRICESButtonPushed();
            app.DISCONNECTALLButtonPushed();
            cla(app.UIAxes2D);
            cla(app.UIAxes3D);
            cla(app.UIAxesCAM);
            for k = 1:6
                Clabel = ['C',num2str(k)];
                app.(Clabel) = [];
            end
            app.startupFcn();
        end

        % Button pushed function: SETButton_2
        function SETButton_2Pushed(app, event)
            app.MaxTime = app.TOTALTIMESECONDSSpinner.Value;
            app.TOTALTIMESECONDSSpinner.Enable = 'off';
            app.SETButton_2.Enable = 'off';
            
        end

        % Button pushed function: RUNEKFButton
        function RUNEKFButtonPushed(app, event)
            numCAM = str2double(app.totalCameras); %Initialise only
            app.createinvC(numCAM); %Initialise only
            EKFTotalTime = 0;
            EKFSampleTime = 1/30;
            dT = EKFSampleTime
            Xk = [0 0 0 0 0 0]'; % INITIAL = [Px Py Pz Vx Vy Vz]';
            Amax = 20; %Initialise only
            eA = sqrt((Amax-(-Amax))^2/12); %Initialise only
            Qk = app.sftQk(dT,eA,eA,eA); %Initialise only
            Pk = app.sftinitialP(0.1,0.1,0.1,0.01,0.01,0.01); %Initialise only
            Frame = 1;
            X_pos(Frame) = Xk(1);
            Y_pos(Frame) = Xk(2);
            Z_pos(Frame) = Xk(3);
            Time_Graph(Frame) = EKFTotalTime;
            for k = 1:numCAM
                openmv = ['openmv',num2str(k)];
                flush(app.(openmv));
            end
            while EKFTotalTime <= app.MaxTime
                tic;
                Frame = Frame + 1;
                disp('Frame: ')
                disp(Frame)
                %GET DATA
                [numCAMFail,Ykm,FPS] = app.sortData(numCAM); %Loop
                disp(Ykm)
                %SET dT
                dT = EKFSampleTime; %Loop

                %STEP 1
                A = app.sftA(dT); %Loop
                Xkp = app.sftXkp(A,Xk); %Loop

                %STEP 2
                Pkp = app.sftPkp(A,Pk,Qk); %Loop

                %STEP 3
                H = app.sftH(numCAMFail,numCAM,Xkp); %Loop
                R = app.sftR(numCAMFail,numCAM); %Loop
                KG = app.sftKG(Pkp,H,R); %Loop

                %STEP 4
                %Ykm = [data(1),data(2)]; %Loop
                Yk = app.sftYk(Ykm,numCAMFail,numCAM); %Loop

                %STEP 5 & STEP 7a (UPDATE Xk)
                C_x = app.sftC_X(numCAMFail,numCAM); %Loop
                UVW = app.sftUVW(C_x,Xkp); %Loop
                HXkp = app.sftHXkp(numCAMFail,numCAM,UVW); %Loop
                Xk = app.sftXk(Xkp,KG,Yk,HXkp); %Loop

                %STEP 6 & STEP 7b (UPDATE Pk)
                Pk = app.sftPk(KG,H,Pkp); %Loop

                %Graphing
                disp(Xk)
                X_pos(Frame) = Xk(1);
                Y_pos(Frame) = Xk(2);
                Z_pos(Frame) = Xk(3);
                Time_Graph(Frame) = EKFTotalTime;
                EKFTotalTime = EKFTotalTime + toc;
                EKFSampleTime = toc;
            end
            cla(app.UIAxes2D);
            cla(app.UIAxes3D);
            plot3(app.UIAxes3D,X_pos,Y_pos,Z_pos,LineWidth=1);
            plot(app.UIAxes2D,Time_Graph,X_pos,'r-',LineWidth=1);
            hold(app.UIAxes2D,'on');
            plot(app.UIAxes2D,Time_Graph,Y_pos,'g-',LineWidth=1);
            plot(app.UIAxes2D,Time_Graph,Z_pos,'b-',LineWidth=1);
            legend(app.UIAxes2D,'X','Y','Z')
        end

        % Button pushed function: TESTEKFButton
        function TESTEKFButtonPushed(app, event)
            % TEST EKF BUTTON WITH SIMULATION CONDITIONS
            cla(app.UIAxes2D);
            cla(app.UIAxes3D);
            numCAM = str2double(app.totalCameras); %Initialise only
            numCAMFail = 0;
            %Set Extrinsics of cameras for simulation
            app.extrinsicsCAM1 = [cos(deg2rad(120)) 0 sin(deg2rad(120)) -4; 
                                         0 1 0 0; 
                                         -sin(deg2rad(120)) 0 cos(deg2rad(120)) 3; 
                                         0 0 0 1];
            app.extrinsicsCAM2 = [1 0 0 0; 
                                         0 cos(deg2rad(120)) -sin(deg2rad(120)) 4; 
                                         0 sin(deg2rad(120)) cos(deg2rad(120)) 3; 
                                         0 0 0 1];
            app.extrinsicsCAM3 = [cos(-2*pi/3) 0 sin(-2*pi/3) 4;
                                         0 1 0 0;
                                         -sin(-2*pi/3) 0 cos(-2*pi/3) 3;
                                         0 0 0 1];
            app.extrinsicsCAM4 = [1 0 0 0;
                                         0 cos(-2*pi/3) -sin(-2*pi/3) -4;
                                         0 sin(-2*pi/3) cos(-2*pi/3) 3;
                                         0 0 0 1];
            app.extrinsicsCAM5 = [eul2rotm([-pi/4,2*pi/3,0],"ZYX") [-4 4 3]';
                                         0 0 0 1];
            app.extrinsicsCAM6 = [eul2rotm([pi/4,0,-2*pi/3],"ZYX") [4 -4 3]';
                                         0 0 0 1];

            %CREATE SIMULATED UVData (need C-Matrices)
            n_iterations = 100;
            n_uv = 50;
            app.createinvC(numCAM); %Initialise only
            UVData = app.testData(numCAM);
            %Initialise all values
            dT = 1/40; %Initial dT
            Xk = [0 0 0 0 0 0]'; % INITIAL = [Px Py Pz Vx Vy Vz]';
            Amax = 20; %Initialise only
            eA = sqrt((Amax-(-Amax))^2/12); %Initialise only
            Qk = app.sftQk(dT,eA,eA,eA); %Initialise only
            Pk = app.sftinitialP(0.1,0.1,0.1,0.01,0.01,0.01); %Initialise only
            X_pos = zeros(n_iterations,1);
            Y_pos = zeros(n_iterations,1);
            Z_pos = zeros(n_iterations,1);
            EKF_sampletime = zeros(n_iterations,1);
            EKF_totaltime = 0;
            for k = 1:n_iterations
                tic;
                disp('iteration:')
                disp(k)
                if k == 99
                    app.bDataCAM2 = true;
                    numCAMFail = numCAMFail-1;
                end
                if k == 100
                    app.bDataCAM4 = true;
                    numCAMFail = numCAMFail-1;
                end
                %GET DATA
                %data = app.getData(openmv); %Loop
                data = UVData(:,k);
                FPS = 80; %Loop

                %SET dT
                dT = 1/FPS; %Loop

                %STEP 1
                A = app.sftA(dT); %Loop
                Xkp = app.sftXkp(A,Xk); %Loop

                %STEP 2
                Pkp = app.sftPkp(A,Pk,Qk); %Loop

                %STEP 3
                H = app.sftH(numCAMFail,numCAM,Xkp); %Loop
                R = app.sftR(numCAMFail,numCAM); %Loop
                KG = app.sftKG(Pkp,H,R); %Loop

                %STEP 4
                Yk = zeros(2*(numCAM-numCAMFail),1); %Loop
                nF = 0;
                for i = 1:numCAM
                    bDataCAM = ['bDataCAM',num2str(i)];
                    if app.(bDataCAM) == true
                        Yk(2*(i-nF)-1) = data(2*i - 1);
                        Yk(2*(i-nF)) = data(2*i);
                    else
                        nF = nF+1;
                        continue;
                    end
                end
                disp('Yk');
                disp(size(Yk));
                %Ykm = [data(1);data(2);data(3);data(4)]; %Loop
                %Yk = app.sftYk(Ykm,numCAM); %Loop

                %STEP 5 & STEP 7a (UPDATE Xk)
                C_x = app.sftC_X(numCAMFail,numCAM); %Loop
                UVW = app.sftUVW(C_x,Xkp); %Loop
                HXkp = app.sftHXkp(numCAMFail,numCAM,UVW); %Loop
                Xk = app.sftXk(Xkp,KG,Yk,HXkp); %Loop

                %STEP 6 & STEP 7b (UPDATE Pk)
                Pk = app.sftPk(KG,H,Pkp); %Loop


                %GRAPHING
                X_pos(k) = Xk(1);
                Y_pos(k) = Xk(2);
                Z_pos(k) = Xk(3);
                EKF_sampletime(k) = toc;
                EKF_totaltime = EKF_totaltime + toc;
            end
            disp('EKF sample times:');
            disp(EKF_sampletime);
            disp('EKF average sample rate:');
            disp(EKF_totaltime/n_iterations);
            disp('EKF total time:')
            disp(EKF_totaltime);
            dI = linspace(0,n_iterations-1,n_iterations);
            plot3(app.UIAxes3D,X_pos,Y_pos,Z_pos,'r-',app.TrueVal(1,:),app.TrueVal(2,:),app.TrueVal(3,:),'k-',LineWidth=1);
            plot(app.UIAxes2D,dI*dT,X_pos,'r-',LineWidth=1);
            hold(app.UIAxes2D,'on');
            plot(app.UIAxes2D,dI*dT,Y_pos,'g-',LineWidth=1);
            plot(app.UIAxes2D,dI*dT,Z_pos,'b-',LineWidth=1);
            plot(app.UIAxes2D,dI*dT,app.TrueVal,'k-',LineWidth=1);
            legend(app.UIAxes2D,'X','Y','Z','True Value')
        end

        % Button pushed function: SetExtrinsicsButton
        function SetExtrinsicsButtonPushed(app, event)
            if isempty(app.CAMERAExtrinsicsListBox.Value)
                return;
            else
                app.CAMEXTRINSICSSwitch.Enable = 'off';
                CAMName = app.CAMERAExtrinsicsListBox.Value;
                CAMNum = extractAfter(CAMName,'CAM ');
                CAMLabel = ['extrinsicsCAM',CAMNum];
                eulx = app.eulX.Value;
                euly = app.eulY.Value;
                eulz = app.eulZ.Value;
                eul = [deg2rad(eulz) deg2rad(euly) deg2rad(eulx)];
                eulstring = [num2str(app.eulZ.Value), ' ', num2str(app.eulY.Value), ' ', num2str(app.eulX.Value)];
                R = eul2rotm(eul,'ZYX');
                t = [(app.posX.Value);
                     (app.posY.Value);
                     (app.posZ.Value)];
                tstring = [num2str(app.posX.Value),' ',num2str(app.posY.Value),' ',num2str(app.posZ.Value)];
                tform = [R t;
                         0 0 0 1];
                app.(CAMLabel) = eye(4)/tform;
                app.UITable2.Data = [app.UITable2.Data;{CAMName,eulstring,tstring}];
                CAMitems = app.CAMERAExtrinsicsListBox.Items;
                app.CAMERAExtrinsicsListBox.Items = CAMitems(~ismember(CAMitems, CAMName));
                app.eulX.Value = 0;
                app.eulY.Value = 0;
                app.eulZ.Value = 0;
                app.posX.Value = 0;
                app.posY.Value = 0;
                app.posZ.Value = 0;
            end
            if isempty(app.CAMERAExtrinsicsListBox.Items)
                app.CreateCAMExtrinsicsPanel.Enable = 'off';
                app.SetExtrinsicsButton.Enable = 'off';
            end
            app.TabGroup.SelectedTab = app.ExtrinsicsTab;
        end

        % Button pushed function: AutoIntrinsicsButton
        function AutoIntrinsicsButtonPushed(app, event)
            numCAM = str2double(app.totalCameras);
            for k = 1:numCAM
                openmv = ['openmv',num2str(k)];
                CamParams = ['CamParams',num2str(k)];
                calibCamParams = ['calibCamParams',num2str(k)];
                CAMName = ['CAM ',num2str(k)];
                app.(openmv).write('INT','string');
                flush(app.(openmv))
                camID = app.(openmv).readline();
                disp(camID)
                i = extractAfter(camID,'0');
                filepath = ['/Users/ferialmeyer/Documents/Zak - UCT Stuff/MATLAB INTRINSIC PARAMS/CAM',num2str(i),'/CAM',num2str(i),'Params_GRAYSCALE.mat'];
                if exist(filepath,'file')
                    file = load(filepath);
                    app.(CamParams) = file.cameraParams;
                    disp(app.(CamParams));
                    app.UITable.Data = [app.UITable.Data;{CAMName,['CAM',num2str(i),'Params_GRAYSCALE.mat']}];
                    CAMitems = app.CAMERAListBox.Items;
                    app.CAMERAListBox.Items = CAMitems(~ismember(CAMitems, CAMName));
                    disp(CAMName)
                    disp('Success')
                else
                    disp('File not found')
                end

                % CALIBRATION INTRINSICS 

                filepath = ['/Users/ferialmeyer/Documents/Zak - UCT Stuff/MATLAB INTRINSIC PARAMS/CAM',num2str(i),'/CAM',num2str(i),'Params_RGB.mat'];
                if exist(filepath,'file')
                    file = load(filepath);
                    app.(calibCamParams) = file.cameraParams;
                    disp(app.(calibCamParams));
                    app.UITable.Data = [app.UITable.Data;{CAMName,['CAM',num2str(i),'Params_RGB.mat']}];
                    CAMitems = app.CAMERAListBox.Items;
                    app.CAMERAListBox.Items = CAMitems(~ismember(CAMitems, CAMName));
                    disp(CAMName)
                    disp('Success')
                else
                    disp('File not found')
                end
            end
            app.AutoIntrinsicsButton.Enable = 'off';
            if isempty(app.CAMERAListBox.Items)
                app.SetIntrinsicsButton.Enable = 'off';
                app.TESTEKFButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2D = uiaxes(app.UIFigure);
            title(app.UIAxes2D, 'Position vs Time')
            xlabel(app.UIAxes2D, 'Time (seconds)')
            ylabel(app.UIAxes2D, 'Position (m)')
            zlabel(app.UIAxes2D, 'Z')
            subtitle(app.UIAxes2D, '[x,y.z] vs [dT]')
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, '3D Position')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.UIAxes3D.XLim = [-1 1.5];
            app.UIAxes3D.YLim = [-1 1.5];
            app.UIAxes3D.ZLim = [-1 1.5];
            app.UIAxesCAM = uiaxes(app.UIFigure);
            title(app.UIAxesCAM, 'CAMERA')
            xlabel(app.UIAxesCAM, 'U')
            ylabel(app.UIAxesCAM, 'V')
            zlabel(app.UIAxesCAM, 'Z')
            app.UIAxesCAM.XLim = [0 640];
            app.UIAxesCAM.YLim = [0 480];
            app.UIAxesCAM.XTick = [0 80 160 240 320 400 480 560 640];
            app.UIAxesCAM.YTick = [0 80 160 240 320 400 480];
            app.ConnectportButton = uibutton(app.UIFigure, 'push');
            app.ConnectportButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectportButtonPushed, true);
            app.ConnectportButton.Text = 'Connect port';
            app.SETButton = uibutton(app.UIFigure, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed2, true);
            app.SETButton.Text = 'SET';
            app.ConnectionlistPanel = uipanel(app.UIFigure);
            app.ConnectionlistPanel.Title = 'Connection list';
            app.GridLayout = uigridlayout(app.ConnectionlistPanel);
            app.CAMERA1LampLabel = uilabel(app.GridLayout);
            app.CAMERA1LampLabel.Text = 'CAMERA 1';
            app.CAMERALAMP1 = uilamp(app.GridLayout);
            app.CAMERA2LampLabel = uilabel(app.GridLayout);
            app.CAMERA2LampLabel.Text = 'CAMERA 2';
            app.CAMERALAMP2 = uilamp(app.GridLayout);
            app.CAMERA3LampLabel = uilabel(app.GridLayout);
            app.CAMERA3LampLabel.Text = 'CAMERA 3';
            app.CAMERALAMP3 = uilamp(app.GridLayout);
            app.CAMERA4LampLabel = uilabel(app.GridLayout);
            app.CAMERA4LampLabel.Text = 'CAMERA 4';
            app.CAMERALAMP4 = uilamp(app.GridLayout);
            app.CAMERA5LampLabel = uilabel(app.GridLayout);
            app.CAMERA5LampLabel.Text = 'CAMERA 5';
            app.CAMERALAMP5 = uilamp(app.GridLayout);
            app.CAMERA6LampLabel = uilabel(app.GridLayout);
            app.CAMERA6LampLabel.Text = 'CAMERA 6';
            app.CAMERALAMP6 = uilamp(app.GridLayout);
            app.PORTLabel_1 = uilabel(app.GridLayout);
            app.PORTLabel_1.Text = 'PORT';
            app.PORTLabel_2 = uilabel(app.GridLayout);
            app.PORTLabel_2.Text = 'PORT';
            app.PORTLabel_3 = uilabel(app.GridLayout);
            app.PORTLabel_3.Text = 'PORT';
            app.PORTLabel_4 = uilabel(app.GridLayout);
            app.PORTLabel_4.Text = 'PORT';
            app.PORTLabel_5 = uilabel(app.GridLayout);
            app.PORTLabel_5.Text = 'PORT';
            app.PORTLabel_6 = uilabel(app.GridLayout);
            app.PORTLabel_6.Text = 'PORT';
            app.DISCONNECTALLButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTALLButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTALLButtonPushed, true);
            app.DISCONNECTALLButton.Text = 'DISCONNECT ALL';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntrinsicsTab = uitab(app.TabGroup);
            app.IntrinsicsTab.Title = 'Intrinsics';
            app.UITable = uitable(app.IntrinsicsTab);
            app.UITable.ColumnName = {'CAMERA NAME'; 'PARAMETER FILE'};
            app.UITable.RowName = {};
            app.ExtrinsicsTab = uitab(app.TabGroup);
            app.ExtrinsicsTab.Title = 'Extrinsics';
            app.UITable2 = uitable(app.ExtrinsicsTab);
            app.UITable2.ColumnName = {'CAMERA NAME'; 'EUL [ZYX] (deg)'; 'POS [XYZ] (meters)'};
            app.UITable2.RowName = {};
            app.SetIntrinsicsButton = uibutton(app.UIFigure, 'push');
            app.SetIntrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @SetIntrinsicsButtonPushed, true);
            app.SetIntrinsicsButton.Text = 'Set Intrinsics';
            app.GetExtrinsicsButton = uibutton(app.UIFigure, 'push');
            app.GetExtrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @GetExtrinsicsButtonPushed, true);
            app.GetExtrinsicsButton.Text = 'Get Extrinsics';
            app.SetWorldPointsButton = uibutton(app.UIFigure, 'push');
            app.SetWorldPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SetWorldPointsButtonPushed, true);
            app.SetWorldPointsButton.Text = 'Set WorldPoints';
            app.CLEARCALIBRATIONMATRICESButton = uibutton(app.UIFigure, 'push');
            app.CLEARCALIBRATIONMATRICESButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARCALIBRATIONMATRICESButtonPushed, true);
            app.CLEARCALIBRATIONMATRICESButton.Text = 'CLEAR CALIBRATION MATRICES';
            app.RESETALLButton = uibutton(app.UIFigure, 'push');
            app.RESETALLButton.ButtonPushedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.RESETALLButton.Text = 'RESET ALL';
            app.SerialPortListListBoxLabel = uilabel(app.UIFigure);
            app.SerialPortListListBoxLabel.Text = 'Serial Port List';
            app.SerialPortListListBox = uilistbox(app.UIFigure);
            app.SerialPortListListBox.Items = {'serialportlist'};
            app.SerialPortListListBox.Value = 'serialportlist';
            app.CAMEXTRINSICSSwitchLabel = uilabel(app.UIFigure);
            app.CAMEXTRINSICSSwitchLabel.Text = 'CAM EXTRINSICS';
            app.CAMEXTRINSICSSwitch = uiswitch(app.UIFigure, 'slider');
            app.CAMEXTRINSICSSwitch.Items = {'GET', 'SET'};
            app.CAMEXTRINSICSSwitch.ItemsData = [false true];
            app.CAMEXTRINSICSSwitch.ValueChangedFcn = createCallbackFcn(app, @CAMEXTRINSICSSwitchValueChanged, true);
            app.CAMEXTRINSICSSwitch.Value = false;
            app.CamerasinuseDropDownLabel = uilabel(app.UIFigure);
            app.CamerasinuseDropDownLabel.Text = 'Cameras in use';
            app.CamerasinuseDropDown = uidropdown(app.UIFigure);
            app.CamerasinuseDropDown.Items = {'2', '3', '4', '5', '6'};
            app.CamerasinuseDropDown.Value = '2';
            app.CAMERAListBoxLabel = uilabel(app.UIFigure);
            app.CAMERAListBoxLabel.Text = 'CAMERA';
            app.CAMERAListBox = uilistbox(app.UIFigure);
            app.CAMERAListBox.Items = {};
            app.CAMERAListBox.Value = {};
            app.TIMELabel = uilabel(app.UIFigure);
            app.TIMELabel.Text = 'TOTAL TIME (SECONDS):';
            app.TOTALTIMESECONDSSpinner = uispinner(app.UIFigure);
            app.SETButton_2 = uibutton(app.UIFigure, 'push');
            app.SETButton_2.ButtonPushedFcn = createCallbackFcn(app, @SETButton_2Pushed, true);
            app.SETButton_2.Text = 'SET';
            app.RUNEKFButton = uibutton(app.UIFigure, 'push');
            app.RUNEKFButton.ButtonPushedFcn = createCallbackFcn(app, @RUNEKFButtonPushed, true);
            app.RUNEKFButton.Text = 'RUN EKF';
            app.TESTEKFButton = uibutton(app.UIFigure, 'push');
            app.TESTEKFButton.ButtonPushedFcn = createCallbackFcn(app, @TESTEKFButtonPushed, true);
            app.TESTEKFButton.Text = 'TEST EKF';
            app.SetExtrinsicsButton = uibutton(app.UIFigure, 'push');
            app.SetExtrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @SetExtrinsicsButtonPushed, true);
            app.SetExtrinsicsButton.Text = 'Set Extrinsics';
            app.FPSLabel = uilabel(app.UIFigure);
            app.FPSLabel.Text = 'FPS: ';
            app.AutoIntrinsicsButton = uibutton(app.UIFigure, 'push');
            app.AutoIntrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIntrinsicsButtonPushed, true);
            app.AutoIntrinsicsButton.Text = 'Auto Intrinsics';
            app.CreateCAMExtrinsicsPanel = uipanel(app.UIFigure);
            app.CreateCAMExtrinsicsPanel.Title = 'Create CAM Extrinsics';
            app.GridLayout3 = uigridlayout(app.CreateCAMExtrinsicsPanel);
            app.eulY = uieditfield(app.GridLayout3, 'numeric');
            app.eulX = uieditfield(app.GridLayout3, 'numeric');
            app.EULERZYXEditFieldLabel_2 = uilabel(app.GridLayout3);
            app.EULERZYXEditFieldLabel_2.Text = 'POS  [XYZ]:';
            app.posX = uieditfield(app.GridLayout3, 'numeric');
            app.eulZ = uieditfield(app.GridLayout3, 'numeric');
            app.posY = uieditfield(app.GridLayout3, 'numeric');
            app.posZ = uieditfield(app.GridLayout3, 'numeric');
            app.CAMERALabel = uilabel(app.GridLayout3);
            app.CAMERALabel.Text = 'CAMERA';
            app.CAMERAExtrinsicsListBox = uilistbox(app.GridLayout3);
            app.CAMERAExtrinsicsListBox.Items = {};
            app.CAMERAExtrinsicsListBox.Value = {};
            app.EULERZYXEditFieldLabel = uilabel(app.GridLayout3);
            app.EULERZYXEditFieldLabel.Text = 'EULER  [ZYX]:';
            app.FeatureLocationinBodyframemetersPanel = uipanel(app.UIFigure);
            app.FeatureLocationinBodyframemetersPanel.Title = 'Feature Location in Body frame (meters)';
            app.GridLayout2 = uigridlayout(app.FeatureLocationinBodyframemetersPanel);
            app.Point1EditFieldy = uieditfield(app.GridLayout2, 'numeric');
            app.Point1EditFieldy.Value = 0.12;
            app.Point2EditFieldy = uieditfield(app.GridLayout2, 'numeric');
            app.Point2EditFieldy.Value = -0.12;
            app.Point3EditFieldy = uieditfield(app.GridLayout2, 'numeric');
            app.Point3EditFieldy.Value = 0.12;
            app.Point4EditFieldy = uieditfield(app.GridLayout2, 'numeric');
            app.Point4EditFieldy.Value = -0.12;
            app.xyLabel = uilabel(app.GridLayout2);
            app.xyLabel.Text = '(x,y)';
            app.xyLabel_2 = uilabel(app.GridLayout2);
            app.xyLabel_2.Text = '(x,y)';
            app.xyLabel_3 = uilabel(app.GridLayout2);
            app.xyLabel_3.Text = '(x,y)';
            app.xyLabel_4 = uilabel(app.GridLayout2);
            app.xyLabel_4.Text = '(x,y)';
            app.REDLabel = uilabel(app.GridLayout2);
            app.REDLabel.Text = 'RED:';
            app.Point1EditFieldx = uieditfield(app.GridLayout2, 'numeric');
            app.Point1EditFieldx.Value = -0.12;
            app.GREENLabel = uilabel(app.GridLayout2);
            app.GREENLabel.Text = 'GREEN:';
            app.Point2EditFieldx = uieditfield(app.GridLayout2, 'numeric');
            app.Point2EditFieldx.Value = 0.12;
            app.BLUELabel = uilabel(app.GridLayout2);
            app.BLUELabel.Text = 'BLUE:';
            app.Point3EditFieldx = uieditfield(app.GridLayout2, 'numeric');
            app.Point3EditFieldx.Value = 0.12;
            app.PURPLELabel = uilabel(app.GridLayout2);
            app.PURPLELabel.Text = 'PURPLE';
            app.Point4EditFieldx = uieditfield(app.GridLayout2, 'numeric');
            app.Point4EditFieldx.Value = -0.12;
            app.Point6Label = uilabel(app.GridLayout2);
            app.Point6Label.Text = 'WHITE';
            app.Point5EditFieldx = uieditfield(app.GridLayout2, 'numeric');
            app.Point5EditFieldx.Value = -0.12;
            app.Point5EditFieldy = uieditfield(app.GridLayout2, 'numeric');
            app.xyLabel_5 = uilabel(app.GridLayout2);
            app.xyLabel_5.Text = '(x,y)';
            app.Point4EditFieldLabel_2 = uilabel(app.GridLayout2);
            app.Point4EditFieldLabel_2.Text = 'CYAN';
            app.Point6EditFieldx = uieditfield(app.GridLayout2, 'numeric');
            app.Point6EditFieldy = uieditfield(app.GridLayout2, 'numeric');
            app.Point6EditFieldy.Value = 0.12;
            app.xyLabel_6 = uilabel(app.GridLayout2);
            app.xyLabel_6.Text = '(x,y)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MastersGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end